classdef AmpliApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        amplificarButton            matlab.ui.control.Button
        fcEditField                 matlab.ui.control.NumericEditField
        fcEditFieldLabel            matlab.ui.control.Label
        GEditField                  matlab.ui.control.NumericEditField
        GEditFieldLabel             matlab.ui.control.Label
        f_triangularEditField       matlab.ui.control.NumericEditField
        f_triangularEditFieldLabel  matlab.ui.control.Label
        Fs_AmpEditField             matlab.ui.control.NumericEditField
        Fs_AmpEditFieldLabel        matlab.ui.control.Label
        selectfileButton            matlab.ui.control.Button
        generatesinButton           matlab.ui.control.Button
        f_sinEditField              matlab.ui.control.NumericEditField
        f_sinEditFieldLabel         matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Label_Fs                    matlab.ui.control.Label
        UIAxes_11                   matlab.ui.control.UIAxes
        UIAxes_12                   matlab.ui.control.UIAxes
        UIAxes_8                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_7                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_9                    matlab.ui.control.UIAxes
        UIAxes_10                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isStereo;
        fs;
        x;
        x_stereo;
        y;%salida de amp
        y_stereo;
    end
    
    methods (Access = private)
        function y=lpf(app,fc,x,fs)
                N=100;
                wn=fc/(fs/2);
                n=-N/2:N/2;

                x_vals = wn * n;

                sinc_vals = ones(size(x_vals));        % Por defecto, sinc(0) = 1
                idx = (x_vals ~= 0);                   
                sinc_vals(idx) = sin(pi * x_vals(idx)) ./ (pi * x_vals(idx));

                h = wn * sinc_vals;

                w = 0.54 - 0.46 * cos(2*pi*(0:N)/N); % Ventana Hamming
                h = h .* w;
                h = h / sum(h);
                
                y=conv(x,h,'same');
         end
        
        function xr = manualResample(app,x, Fs_out, Fs_in)
            t_in=(0:length(x)-1)/Fs_in;
            t_out=(0:1/Fs_out:t_in(end))';
            
            xr=interp1(t_in,x,t_out,'spline');
        end

        function y = ampli_graf(app)

            Fs_Amp = app.Fs_AmpEditField.Value;
            xAmp=app.manualResample(app.x,Fs_Amp,app.fs);
            f_tri=app.f_triangularEditField.Value;
            G=app.GEditField.Value;
            
            N=length(xAmp);         %N muestras
            T=N/Fs_Amp;             %duracion
            t =linspace(0,T,N);     %eje temporal
            f=linspace(0,Fs_Amp,N); %eje frecuencial
            
            y1=1.05*app.triangular(f_tri,Fs_Amp,N);
            
            z1=app.comparador(xAmp, y1,G);
            
            
            yAmp=app.lpf(app.fcEditField.Value,z1,Fs_Amp);
            
            app.y=app.manualResample(yAmp,app.fs,Fs_Amp);
            
            t_lim=0.001; %plot hasta 0.001s
            
            plot(app.UIAxes,t(1:t_lim*Fs_Amp),xAmp(1:t_lim*Fs_Amp));
            
            semilogx(app.UIAxes_5,f,abs(fft(xAmp)));
           
            plot(app.UIAxes_2,t(1:t_lim*Fs_Amp),y1(1:t_lim*Fs_Amp));
            
            semilogx(app.UIAxes_6,f,abs(fft(y1)));


            plot(app.UIAxes_3,t,z1);
            
            semilogx(app.UIAxes_7,f,abs(fft(z1)));


            plot(app.UIAxes_4,t(1:t_lim*Fs_Amp),yAmp(1:t_lim*Fs_Amp));
            
            semilogx(app.UIAxes_8,f,abs(fft(yAmp)));
            
            N=length(app.y);         %N muestras
            T=N/app.fs;             %duracion
            t =linspace(0,T,N);     %eje temporal
            f=linspace(0,app.fs,N); %eje frecuencial
            plot(app.UIAxes_12,t(1:t_lim*app.fs),app.y(1:t_lim*app.fs));
            semilogx(app.UIAxes_11,f,abs(fft(app.y)));


            y=app.y; %señal amplificada
        end
    
         % Enfinestrament temporal
        function w = hamming_custom(~,L)
            if L == 1
                w = 1;
            else
                n = (0:L-1)';
                w = 0.54 - 0.46*cos(2*pi*n/(L-1));
            end
        end
        
        function [bloques, ventanas] = segmentar_hamming(app,s, block_size, overlap)
            
            s = s(:).'; % Assegurar vector fila
            step = block_size - overlap;
            M = length(s);
        
            n_blocks = ceil((M - overlap) / step);
        
            % Zero padding
            padded_len = (n_blocks - 1) * step + block_size;
            s_padded = [s, zeros(1, padded_len - M)];
        
            % Inicializar la matriz de bloques y ventanas
            bloques = zeros(n_blocks, block_size);
            ventanas = zeros(n_blocks, block_size);
        
            w = app.hamming_custom(block_size).'; % Ventana de Hamming como fila
        
            for k = 0:n_blocks-1
                idx_start = k * step + 1;
                idx_end = idx_start + block_size - 1;
                bloque = s_padded(idx_start:idx_end);
                bloques(k+1, :) = bloque .* w;
                ventanas(k+1, :) = w;
            end
        end
        
        function s_rec = ajuntar_hamming(~, bloques, ventanas, block_size, overlap, original_length)
            
            step = block_size - overlap;
            n_blocks = size(bloques, 1);
            total_len = (n_blocks-1)*step + block_size;
            s_rec = zeros(1, total_len);
            norm_factor = zeros(1, total_len);
        
            for k = 0:n_blocks-1
                idx_start = k*step + 1;
                idx_end = idx_start + block_size - 1;
                s_rec(idx_start:idx_end) = s_rec(idx_start:idx_end) + bloques(k+1, :);
                norm_factor(idx_start:idx_end) = norm_factor(idx_start:idx_end) + ventanas(k+1, :);
            end
        
            % Normaliza para corregir la suma en regiones solapadas
            s_rec = s_rec ./ (norm_factor + eps);
        
            % Si se especifica la longitud original, recorta
            if nargin == 5
                s_rec = s_rec(1:original_length);
            end
        end
        function comp=comparador(app,x,tri, G)
                comp=ones(1,length(x));
                for i=1:length(x)
                    if x(i)<tri(i)
                        comp(i)=-1*G;
                    else
                        comp(i)=1*G;
                    end
                end  
            end 
            
        function tri=triangular(app,f_tri,Fs_Amp,N)
                T=(1/f_tri)*Fs_Amp;
                tri=ones(1,N);
                for i=1:N
                    if mod(i,T)<T/2
                        tri(i)=mod(i,T)*4/T-1;
                    else
                        tri(i)=-4*mod(i-T/2,T)/T+1;
                    end
                end 
              
            end

        function showx(app)
            N=length(app.x);
            t=linspace(0,N/app.fs,N);
            f=linspace(0,app.fs,N);
            plot(app.UIAxes_10,t,app.x);
            semilogx(app.UIAxes_9,f,abs(fft(app.x)));
            
            app.UIAxes_5.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_6.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_7.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_8.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_9.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_11.XLim=[20,app.Fs_AmpEditField.Value/2];
        end
        
        function y=ampli_en_seg(app)
            %...
        end
    end
    
    methods (Access = public)
        function y = ampli(app,x,fs)%stereo o mono, autodetercta
            
            if size(x,2)==2
                app.x=x(:,1);

            else
                app.x=x;

            end
                y=app.ampli_graf();
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: amplificarButton
        function amplificarButtonPushed(app, event)
            if isempty(app.x)
                disp('input is empty');
            else
                app.ampli(app.x,app.fs);
            end
        end

        % Button pushed function: selectfileButton
        function selectfileButtonPushed(app, event)
            [file, path]=uigetfile({'*.wav;*.mp3'});
            if isequal(file,0)
                disp("not found")
            else
            app.Label_Fs.Text=num2str(app.fs);
            [x,app.fs]=audioread(fullfile(path,file));
            if size(x,2)==2
                app.isStereo=true;
                app.x=x(:,1);

            else
                app.isStereo=false;
                app.x=x;
            end
            app.showx();
            
            end
        end

        % Button pushed function: generatesinButton
        function generatesinButtonPushed(app, event)
            f=app.f_sinEditField.Value;%hz
            T=0.01;%s
            app.fs=48000;
            app.x = sin(2*pi*f*(0:1/app.fs:T));
            
            app.showx();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, '\color[rgb]{0.94,0.94,0.94}entrada')
            xlabel(app.UIAxes_10, 't (s)')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_10.XLim = [0 0.001];
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, '\color[rgb]{0.94,0.94,0.94}FFT')
            xlabel(app.UIAxes_9, 'f (Hz)')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.XLim = [20 490000];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '\color[rgb]{0.94,0.94,0.94}entrada (resample)')
            xlabel(app.UIAxes, 't (s)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 0.001];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '\color[rgb]{0.94,0.94,0.94}FFT')
            xlabel(app.UIAxes_5, 'f (Hz)')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XLim = [20 490000];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '\color[rgb]{0.94,0.94,0.94}triangular')
            xlabel(app.UIAxes_2, 't (s)')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 0.001];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '\color[rgb]{0.94,0.94,0.94}FFT')
            xlabel(app.UIAxes_6, 'f (Hz)')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XLim = [20 490000];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '\color[rgb]{0.94,0.94,0.94}compadador')
            xlabel(app.UIAxes_3, 't (s)')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [0 0.001];
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, '\color[rgb]{0.94,0.94,0.94}FFT')
            xlabel(app.UIAxes_7, 'f (Hz)')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XLim = [20 490000];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '\color[rgb]{0.94,0.94,0.94}salida')
            xlabel(app.UIAxes_4, 't (s)')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XLim = [0 0.001];
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, '\color[rgb]{0.94,0.94,0.94}FFT')
            xlabel(app.UIAxes_8, 'f (Hz)')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.XLim = [20 490000];
            app.UIAxes_12 = uiaxes(app.UIFigure);
            title(app.UIAxes_12, '\color[rgb]{0.94,0.94,0.94}salida(resample)')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_12.XLim = [0 0.001];
            app.UIAxes_11 = uiaxes(app.UIFigure);
            title(app.UIAxes_11, '\color[rgb]{0.94,0.94,0.94}Title')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_11.XLim = [20 490000];
            app.Label_Fs = uilabel(app.UIFigure);
            app.Label_Fs.Text = '48000';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'input_fs:';
            app.f_sinEditFieldLabel = uilabel(app.UIFigure);
            app.f_sinEditFieldLabel.Text = 'f_sin:';
            app.f_sinEditField = uieditfield(app.UIFigure, 'numeric');
            app.f_sinEditField.ValueDisplayFormat = '%.0f';
            app.f_sinEditField.Value = 1000;
            app.generatesinButton = uibutton(app.UIFigure, 'push');
            app.generatesinButton.ButtonPushedFcn = createCallbackFcn(app, @generatesinButtonPushed, true);
            app.generatesinButton.Text = 'generate sin';
            app.selectfileButton = uibutton(app.UIFigure, 'push');
            app.selectfileButton.ButtonPushedFcn = createCallbackFcn(app, @selectfileButtonPushed, true);
            app.selectfileButton.Text = 'select file';
            app.Fs_AmpEditFieldLabel = uilabel(app.UIFigure);
            app.Fs_AmpEditFieldLabel.Text = 'Fs_Amp:';
            app.Fs_AmpEditField = uieditfield(app.UIFigure, 'numeric');
            app.Fs_AmpEditField.ValueDisplayFormat = '%.0f';
            app.Fs_AmpEditField.Value = 300000;
            app.f_triangularEditFieldLabel = uilabel(app.UIFigure);
            app.f_triangularEditFieldLabel.Text = 'f_triangular';
            app.f_triangularEditField = uieditfield(app.UIFigure, 'numeric');
            app.f_triangularEditField.ValueDisplayFormat = '%.0f';
            app.f_triangularEditField.Value = 30000;
            app.GEditFieldLabel = uilabel(app.UIFigure);
            app.GEditFieldLabel.Text = 'G:';
            app.GEditField = uieditfield(app.UIFigure, 'numeric');
            app.GEditField.Value = 0.5;
            app.fcEditFieldLabel = uilabel(app.UIFigure);
            app.fcEditFieldLabel.Text = 'fc';
            app.fcEditField = uieditfield(app.UIFigure, 'numeric');
            app.fcEditField.ValueDisplayFormat = '%.0f';
            app.fcEditField.Value = 20000;
            app.amplificarButton = uibutton(app.UIFigure, 'push');
            app.amplificarButton.ButtonPushedFcn = createCallbackFcn(app, @amplificarButtonPushed, true);
            app.amplificarButton.Text = 'amplificar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AmpliApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end