classdef EqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        ToggleTool                    matlab.ui.container.toolbar.ToggleTool
        addButton                     matlab.ui.container.toolbar.PushTool
        editButton                    matlab.ui.container.toolbar.PushTool
        deleteButton                  matlab.ui.container.toolbar.PushTool
        saveButton                    matlab.ui.container.toolbar.PushTool
        exportButton                  matlab.ui.container.toolbar.PushTool
        Export                        matlab.ui.container.Panel
        FormatDropDown                matlab.ui.control.DropDown
        TipusLabel                    matlab.ui.control.Label
        XButton_2                     matlab.ui.control.Button
        ExporttoButton                matlab.ui.control.Button
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        PauseButton                   matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        AudioSlider                   matlab.ui.control.Slider
        FiltreDropDown                matlab.ui.control.DropDown
        colorrgb094094094FiltreLabel  matlab.ui.control.Label
        AfegirfiltrePanel             matlab.ui.container.Panel
        XButton                       matlab.ui.control.Button
        AfegirButton                  matlab.ui.control.Button
        TipusdefiltreDropDown         matlab.ui.control.DropDown
        TipusdefiltreDropDownLabel    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        AfegirfiltreMenu              matlab.ui.container.Menu
    end

    
    properties (Access = private)
        fs = 48000;                                                         % sapling frequency
        nextID = 1;                                                         % ID assigned to the next filter

        audio;                                                              % Input audio
        audioName;                                                          % Input audio name

        audioSegsL;                                                         % Segmented Input audio
        audioSegsR;                                                         % L and R for stereo audio, just L for mono
        audioEQL;                                                           % Audio segments equalized
        audioEQR;
        audioWindow = 1;                                                    % Number of the current showed window
        audioFiltrat;                                                       % Full equalized audio

        filtersOn = 1;                                                      % Activate/Desactivate filters

        ExportMode = 'audio'; % 'audio' o 'param'                           % Export/Save panel control


        N = 4096;                                                           % Length of the segments
        Panell;                                                             % Selected filter management app 
    end
    
    properties (Access = public)
        filtres;                                                            % Matrix that contains frequential response of every filter
        filtresParam;                                                       % Matrix that contains parameters used to generate every filter
        H;                                                                  % Equalizer total frequency response 
        player;                                                             % audioplayer object, contains 
        isChanging = 0;                                                     % Indicates if a filter is currently changing
    end
    
    methods (Access = public)
        
        function actualitza_eq(app)                                         % Function that updates the equalizer
            
            [s, l]= size(app.filtres);                                      % Generate base vector and neutral elements of the filter as a base
            f = logspace(log10(10), log10(app.fs/2), app.N);
            app.H=ones(1,app.N);
            H1=ones(1,app.N);                                               
            if (l == app.N)
                for i=1:s
                    H1=H1 .* app.filtres(i,:);                              % Add every filter designed to the final frequency response and plot
                    area(app.UIAxes, f, 20*log10(abs(app.filtres(i,:))),'FaceAlpha',.2,'EdgeAlpha',.2);
                    hold(app.UIAxes,'on');
                end
            end
            app.H = app.H .*H1;                                             % Store the final frequency response in H and plot it
            semilogx(app.UIAxes, f, 20*log10(abs(app.H)), "w");
            hold(app.UIAxes,'off');

            if(~isplaying(app.player))                                      % If the audio is not playing 
                app.actualitza_ft;                                          % update the FT of the signal
                pause(0.05);
            else
                if app.isChanging == 0                                      % If the audio is playing and any of the filters is currently changing, 
                    pause(app.player);                                      % recalculate the audio output and keep playing
                    app.audioFiltrat = app.filtrar(app.audio(:,1), app.H, app.N*2, app.N);
                    if size(app.audio, 2)==2
                        app.audioFiltrat = [app.audioFiltrat; app.filtrar(app.audio(:,2), app.H, app.N*2, app.N)];
                    end
                    app.player = audioplayer(app.audioFiltrat, app.fs);
                    a = app.audioWindow * app.N/2 + app.N/2;
                    play(app.player,a);
                end
            end
        end

        function actualitza_ft(app)                                         % Function that updates the FT of the signal
            f = logspace(log10(10), log10(app.fs/2), app.N);
                                                                            % Calculate the FT of the audio signal, filter it and plot it
            block_filt_fft = app.audioEQL(round(app.audioWindow), :).* app.H;
            a = area(app.UIAxes_2, f, 20*log10(abs(block_filt_fft)*2/app.fs));
            a.EdgeColor = [0.5 0.5 0.5];
            a.FaceColor = [0.15,0.15,0.15];
            a.FaceAlpha = 0.75;
            a.LineWidth = 1.5;
            hold(app.UIAxes_2,'on');

            if size(app.audio, 2)==2                                        % If the audio is stereo, do it also for the second channel (R)
                block_filt_fft = app.audioEQR(round(app.audioWindow), :).*app.H;
                a = area(app.UIAxes_2, f, 20*log10(abs(block_filt_fft)*2/app.fs));
                a.EdgeColor = [0.5 0.5 0.5];
                a.FaceColor = [0.15,0.15,0.15];
                a.FaceAlpha = 0.75;
                a.LineWidth = 1.5;
            end
            hold(app.UIAxes_2,'off');
        end

    end
    
    methods (Access = private)
        
        % Enfinestrament temporal
        function w = hamming_custom(~,L)                                    %Function that generates a hamming window of length L
            if L == 1
                w = 1;
            else
                n = (0:L-1)';
                w = 0.54 - 0.46*cos(2*pi*n/(L-1));
            end
        end
        
        function [blocks, windows] = segmentar_hamming(app,s, block_size, overlap) 
                                                                            % Function that splits the signal into windows
                                                                            % with an overlap and using a hamming window shape
            s = s(:).';
            step = block_size - overlap;
            M = length(s);
        
            n_blocks = ceil((M - overlap) / step);
        
            padded_len = (n_blocks - 1) * step + block_size;                % Zero padding
            s_padded = [s, zeros(1, padded_len - M)];
        
            blocks = zeros(n_blocks, block_size);
            windows = zeros(n_blocks, block_size);
        
            w = app.hamming_custom(block_size).';
        
            for k = 0:n_blocks-1                                            % Segmentation
                idx_start = k * step + 1;
                idx_end = idx_start + block_size - 1;
                bloque = s_padded(idx_start:idx_end);
                blocks(k+1, :) = bloque .* w;
                windows(k+1, :) = w;
            end
        end
        
        function s_rec = ajuntar_hamming(~, blocks, windows, block_size, overlap, original_length)
                                                                            % Function that merges a series of segmented 
                                                                            % hamming shaped windows into a recovered audio signal
            step = block_size - overlap;
            n_blocks = size(blocks, 1);
            total_len = (n_blocks-1)*step + block_size;
            s_rec = zeros(1, total_len);
            norm_factor = zeros(1, total_len);
        
            for k = 0:n_blocks-1                                            % Segment merging
                idx_start = k*step + 1;
                idx_end = idx_start + block_size - 1;
                s_rec(idx_start:idx_end) = s_rec(idx_start:idx_end) + blocks(k+1, :);
                norm_factor(idx_start:idx_end) = norm_factor(idx_start:idx_end) + windows(k+1, :);
            end
                                                                            % Normazation to correct the overlapped zones
            s_rec = s_rec ./ (norm_factor + eps);
                                                                            % Cut the signal according to original length if specified
            if nargin == 5
                s_rec = s_rec(1:original_length);
            end
        end
        
        function s_blocks = tf_blocs(app, s, H, block_size, overlap)
                                                                            % function that returns a matrix containing
                                                                            % the signal segmented and tranformed to be plotted
            s = s(:).'; 
            Nfft = length(H);
        
            [bloques, ~] = app.segmentar_hamming(s, block_size, overlap);   % Segmentation process
            n_blocks = size(bloques, 1);
        
            blocks_tf = zeros(size(bloques));
        
        
            f = linspace(10, app.fs/2, Nfft);
            f_l = logspace(log10(10), log10(app.fs/2), Nfft);
        
            for k = 1:n_blocks                                              % FT block by block, and interpolation to match the filter scale
                bloque = bloques(k, :);
                bloque_fft = fft(bloque, 2*Nfft);
                bloque_log = interp1(f(1:end), bloque_fft(1:block_size), f_l, 'spline', 'extrap');
                blocks_tf(k, :) = bloque_log(1:block_size);
        
            end
            s_blocks = blocks_tf;
            
        end

        function s_filtrada = filtrar(app, s, H, block_size, overlap)
                                                                            % Function that filters the full signal by blocks
                s = s(:).'; 
                Nfft = length(H);                                           % Segmentation process
                [bloques, ventanas] = app.segmentar_hamming(s, block_size, overlap);
                n_blocks = size(bloques, 1);
            
                bloques_filtrados = zeros(size(bloques));
                f = linspace(10, app.fs/2, Nfft);
                f_l = logspace(log10(10), log10(app.fs/2), Nfft);

                H1 = interp1(f_l, H, f, 'spline', 0);                       % Interpolation to match the filter response scale
            
            for k = 1:n_blocks                                              % Filter block by block
                bloque = bloques(k, :);
                bloque_fft = fft(bloque, Nfft*2);
                bloque_filtrado_fft = bloque_fft .* [H1, flip(H1)];
                bloque_filtrado = real(ifft(bloque_filtrado_fft, Nfft*2));
                bloques_filtrados(k, :) = bloque_filtrado;
            end
                                                                            % Merge the blocks
                s_filtrada = app.ajuntar_hamming(bloques_filtrados, ventanas, block_size, overlap, length(s));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, audio, save)
                                                                            % Start function. sets up the initial variables
            app.audioName = audio;                                          % Get the audio data from the file given as a parameter
            [app.audio, app.fs] = audioread(audio);

            app.player = audioplayer(app.audio, app.fs);

            app.H=ones(1,app.N);                                            % Initialize H as a neutral value vector
                                                                            % Setup audio variables
            app.audioSegsL = app.tf_blocs(app.audio(:, 1), app.H, app.N, app.N/2);
            app.audioEQL = app.audioSegsL;
            app.audioFiltrat = app.audio;

            if size(app.audio, 2)==2                                        % Contemplate stereo
                app.audioSegsR = app.tf_blocs(app.audio(:, 2), app.H, app.N, app.N/2);
                app.audioEQR = app.audioSegsR;
            end

            app.AudioSlider.Limits = [1 size(app.audioSegsL, 1)];

            if nargin >2                                                    % if there is a second parameter, setup saved filters
                p = load(save, 'param');
                param = p.param;
                for i = 1:size(param, 1)
                    if param(i,6)==1
                        app.TipusdefiltreDropDown.Value='Bell';
                    elseif param(i,6)==2
                        app.TipusdefiltreDropDown.Value='Shelf';
                    elseif param(i,6)==3
                        app.TipusdefiltreDropDown.Value='Band';
                    elseif param(i,6)==4
                        app.TipusdefiltreDropDown.Value='HighCut';
                    elseif param(i,6)==5
                        app.TipusdefiltreDropDown.Value='LowCut';
                    end
                    app.AfegirButtonPushed;
                    delete(app.Panell);
                    app.filtresParam(i, :) = param(i, :);
                    app.FiltreDropDownValueChanged;
                end
            end

            app.actualitza_eq;                                              % Display the starting position

        end

        % Value changed function: FiltreDropDown
        function FiltreDropDownValueChanged(app, event)
            ID = app.FiltreDropDown.Value;                                  % Filter selection management function (Dropdown callback)
            if ~isempty(app.Panell)                                         % If there is an open filter management app, close it
                if isvalid(app.Panell)
                    app.Panell.valors;
                    delete(app.Panell);
                end
            end
            if ID~=0                                                        % Open a filter management app, according to the filter selected (if any)
                if app.filtresParam(ID,6)==1
                    app.Panell = PanellBell(app, app.filtresParam(ID,1), app.filtresParam(ID,2), double(app.filtresParam(ID,3)), app.filtresParam(ID,4), app.fs, app.N);
                elseif app.filtresParam(ID,6)==2
                    app.Panell = PanellShelf(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.filtresParam(ID,3), app.filtresParam(ID,4), app.filtresParam(ID,5), app.fs, app.N);
                elseif app.filtresParam(ID,6)==3
                    app.Panell = PanellBanda(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.filtresParam(ID,3), app.filtresParam(ID,4), app.filtresParam(ID,5), app.fs, app.N);
                elseif app.filtresParam(ID,6)==4
                    app.Panell = PanellPB(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.fs, app.N);
                elseif app.filtresParam(ID,6)==5
                    app.Panell = PanellPA(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.fs, app.N);
                end
            else
                app.actualitza_eq;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.player);                                               % Function that stops the audioplayer, closes the linked apps and closes this app 
            delete(app.Panell)
            delete(app)
        end

        % Menu selected function: AfegirfiltreMenu
        function AfegirfiltreMenuSelected(app, event)
            app.AfegirfiltrePanel.Visible = 'on';                           % Function that opens the Add filter panel (Menu callback)
        end

        % Button pushed function: AfegirButton
        function AfegirButtonPushed(app, event)
                                                                            % Function that adds a new filter to the Equalizer (Button callback)
            if ~isempty(app.Panell)                                         % If there is an open filter management app, close it
                if isvalid(app.Panell)
                    app.Panell.valors;
                    delete(app.Panell)
                end
            end
            filter_type = app.TipusdefiltreDropDown.Value;                  % Set the filter type, ID, and Name
            ID = size(app.filtres, 1)+1;
            num = ' (' + string(app.nextID) + ')';

            switch filter_type                                              % Generate the new filter with starting values through the corresponding management app
                case 'Bell'
                    fc=1000;
                    Q=1;
                    G=3;
                    app.filtresParam = [app.filtresParam; ID, fc, Q, G, 0, 1];
                    app.Panell = PanellBell(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.filtresParam(ID,3), app.filtresParam(ID,4), app.fs, app.N);
                case 'Shelf'
                    fc=676;
                    S=1;
                    G=3;
                    lH = 0;
                    app.filtresParam = [app.filtresParam; ID, fc, S, G, lH, 2];
                    app.Panell = PanellShelf(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.filtresParam(ID,3), app.filtresParam(ID,4), app.filtresParam(ID,5), app.fs, app.N);
                case 'Band'
                    fc=1000;
                    BW=2;
                    G=3;
                    Q=3;
                    app.filtresParam = [app.filtresParam; ID, fc, BW, Q, G, 3];
                    app.Panell = PanellBanda(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.filtresParam(ID,3), app.filtresParam(ID,4), app.filtresParam(ID,5), app.fs, app.N);
                case 'HighCut'
                    fc=20000;
                    app.filtresParam = [app.filtresParam; ID, fc, 0, 0, 0, 4];
                    app.Panell = PanellPB(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.fs, app.N);
                case 'LowCut'
                    fc=20;
                    app.filtresParam = [app.filtresParam; ID, fc, 0, 0, 0, 5];
                    app.Panell = PanellPA(app, app.filtresParam(ID,1), app.filtresParam(ID,2), app.fs, app.N);
            end
            nou =  filter_type + num;                                       % Add the new filter to the filter list
            app.FiltreDropDown.Items = [app.FiltreDropDown.Items nou];
            app.FiltreDropDown.ItemsData = [app.FiltreDropDown.ItemsData ID];
            app.FiltreDropDown.Value = ID;
            app.nextID = app.nextID+1;
            app.AfegirfiltrePanel.Visible = 'off';
        end

        % Value changing function: AudioSlider
        function AudioSliderValueChanging(app, event)
                                                                            % Function that updates the current audio window when the audioSlider moves (Slider callback)
            if isplaying(app.player)                                        % Identify if audioplayer is playing
                pause(app.player);
                playing = 1;
            else
                playing = 0;
            end
            
            changingValue = event.Value;
            app.audioWindow = changingValue;                                % Update the audio window

            app.actualitza_ft;
            pause(0.05);

            if playing == 1                                                 % If audiplayer was playing, resume from the actual position
                a = app.audioWindow * app.N/2 + app.N/2;
                play(app.player,a);
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
                                                                            % Function that starts playing the filtered audio from the audio window position (Button callback)
            if app.filtersOn == 1                                           % If filters are active, prepare the filtered audio
                app.audioFiltrat = app.filtrar(app.audio(:,1), app.H, app.N*2, app.N);
                if size(app.audio, 2)==2                                    % Contemplate stereo case
                    app.audioFiltrat= [app.audioFiltrat; app.filtrar(app.audio(:,2), app.H, app.N*2, app.N)];
                end
                app.player = audioplayer(app.audioFiltrat, app.fs);
            else                                                            % If filters are not active, prepare the original audio
                app.player = audioplayer(app.audio, app.fs);
            end
            a = app.audioWindow * app.N/2 + app.N/2;                        % Determinate the actual position in the audio vector and start playing
            play(app.player,a);
            
            while(isplaying(app.player))                                    % while audioplayer is playing, keep updating the slider position and the signal on the screen
                newV = round(app.player.CurrentSample*2/app.N);
                if newV~=0
                    if newV < app.AudioSlider.Limits(1, 2)
                        app.AudioSlider.Value = newV;
                    end
                end
                app.audioWindow = app.AudioSlider.Value;
                app.actualitza_ft;
                pause(0.05);
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.player);                                              % Function that pauses the audioplayer (Button callback)
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.AfegirfiltrePanel.Visible = 'off';                          % Function that closes the add filter panel (Button callback)
        end

        % Callback function: addButton
        function addButtonClicked(app, event)
            app.AfegirfiltrePanel.Visible = 'on';                           % Function that shows the add filter panel (Button callback)
        end

        % Callback function: editButton
        function editButtonClicked(app, event)
            app.FiltreDropDownValueChanged;                                 % Function that opens the filter management app of the current selected filter (Button callback) 
        end

        % Callback function: deleteButton
        function deleteButtonClicked(app, event)
            ID = app.FiltreDropDown.Value;                                  % Function that removes a filter from the Equalizer (Button callback)
            if ~isempty(app.Panell)                                         % If there is an open filter management app, close it
                if isvalid(app.Panell)
                    app.Panell.valors;
                    delete(app.Panell);
                end
            end
            if ID~=0                                                        % Remove the current selected filter from the parameters and frequential response array
                [s1, s2] = size(app.filtres);                               % and rearrange the others for optimization
                if s1~=1
                    i=ID;
                    if s1~=ID
                        for i= ID+1:s1
                            app.filtres(i-1,1:s2) = app.filtres(i,1:s2);
                            app.filtresParam(i-1,2:6) = app.filtresParam(i,2:6);
                            app.FiltreDropDown.Items(i) = app.FiltreDropDown.Items(i+1);
                        end
                    end                                                     % Adjust the size of the data
                    app.FiltreDropDown.ValueIndex = 1;
                    app.FiltreDropDown.Items = app.FiltreDropDown.Items(1:i);
                    app.FiltreDropDown.ItemsData = app.FiltreDropDown.ItemsData(1:i);
                    app.filtres = app.filtres(1:i-1,:);
                    app.filtresParam = app.filtresParam(1:i-1,:);
                    app.actualitza_eq;
                else                                                        % if the removed filter is the last one, set the variables to empty
                    app.filtres = double.empty;
                    app.filtresParam = double.empty;
                    app.FiltreDropDown.ItemsData=0;
                    app.FiltreDropDown.Items = {'Selecciona un filtre'};
                    app.actualitza_eq;
                    
                end
                
            end
        end

        % Callback function: exportButton
        function exportButtonClicked(app, event)
            app.ExportMode = 'audio';                                       % Function that opens the Export panel (Button callback)
            app.Export.Title = 'Export audio';                             
            app.FileNameEditField.Value = app.audioName;
            app.FormatDropDown.Items = {'wav', 'mp3'};
            app.FormatDropDown.Value = 'wav';
            app.Export.Visible = 'on';
        end

        % Button pushed function: ExporttoButton
        function ExporttoButtonPushed(app, event)
            carpeta = uigetdir;                                             % Function that exports an audio file/ saves the filter parameters (Button callback)
            if carpeta ~= 0                                                 % Choose the destination directory
                nomFitxer = app.FileNameEditField.Value;
                formatFitxer = app.FormatDropDown.Value;
        
                [~,~,ext] = fileparts(nomFitxer);                           % Set the file name
                if isempty(ext)
                    nomFitxer = [nomFitxer, '.', formatFitxer];
                end
        
                fullPath = fullfile(carpeta, nomFitxer);
        
                switch app.ExportMode                                       % Export the eudio file
                    case 'audio'
                        audiowrite(fullPath, app.audio, app.fs);
        
                    case 'param'                                            % Save the parameters file
                        param = app.filtresParam;
                        save(fullPath, 'param');
                end
        
                app.Export.Visible = 'off';
            end
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;                            % Fuinction that updates the name of the audio file (Edit Field callback)
            app.audioName = value;
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.Export.Visible = 'off';                                     % Function that closes the export/save panel (Button callback)
        end

        % On callback: ToggleTool
        function ToggleToolOn(app, event)
            app.filtersOn = 1;                                              % Function that activates the filters (Toggle on callback)
            if isplaying(app.player)                                        % If the audioplayer is playing, make sure that the audio playing is the filtered one
                sample = app.player.CurrentSample;
                app.player = audioplayer(app.audioFiltrat, app.fs);
                play(app.player, sample)
            end
        end

        % Off callback: ToggleTool
        function ToggleToolOff(app, event)
            app.filtersOn = 0;                                              % Function that deactivates the filters (Toggle on callback)
            if isplaying(app.player)                                        % If the audioplayer is playing, make sure that the audio playing is the not filtered one
                sample = app.player.CurrentSample;
                app.player = audioplayer(app.audio, app.fs);
                play(app.player, sample)
            end
        end

        % Callback function: saveButton
        function saveButtonClicked(app, event)
            app.ExportMode = 'param';                                       % Function that opens the Save panel (Button callback)
            app.Export.Title = 'Save filter parameters';
            app.FileNameEditField.Value = 'datos';
            app.FormatDropDown.Items = {'mat'}; % Només .mat per ara
            app.FormatDropDown.Value = 'mat';
            app.Export.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.State = 'on';
            app.ToggleTool.Icon = fullfile(pathToMLAPP, 'EQAssets', 'Filtrar.png');
            app.ToggleTool.OffCallback = createCallbackFcn(app, @ToggleToolOff, true);
            app.ToggleTool.OnCallback = createCallbackFcn(app, @ToggleToolOn, true);
            app.addButton = uipushtool(app.Toolbar);
            app.addButton.ClickedCallback = createCallbackFcn(app, @addButtonClicked, true);
            app.addButton.Icon = fullfile(pathToMLAPP, 'EQAssets', 'Afegir.png');
            app.addButton.Separator = 'on';
            app.editButton = uipushtool(app.Toolbar);
            app.editButton.ClickedCallback = createCallbackFcn(app, @editButtonClicked, true);
            app.editButton.Icon = fullfile(pathToMLAPP, 'EQAssets', 'Editar.png');
            app.deleteButton = uipushtool(app.Toolbar);
            app.deleteButton.ClickedCallback = createCallbackFcn(app, @deleteButtonClicked, true);
            app.deleteButton.Icon = fullfile(pathToMLAPP, 'EQAssets', 'Eliminar.png');
            app.saveButton = uipushtool(app.Toolbar);
            app.saveButton.ClickedCallback = createCallbackFcn(app, @saveButtonClicked, true);
            app.saveButton.Icon = fullfile(pathToMLAPP, 'EQAssets', 'Guardar.png');
            app.saveButton.Separator = 'on';
            app.exportButton = uipushtool(app.Toolbar);
            app.exportButton.ClickedCallback = createCallbackFcn(app, @exportButtonClicked, true);
            app.exportButton.Icon = fullfile(pathToMLAPP, 'EQAssets', 'Exportar.png');
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [10 30000];
            app.UIAxes_2.YLim = [-150 0];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XScale = 'log';
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YAxisLocation = 'right';
            app.UIAxes_2.YTick = [-150 -100 -50 0];
            colormap(app.UIAxes_2, 'colorcube')
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [10 24000];
            app.UIAxes.YLim = [-24 12];
            app.UIAxes.XTick = [20 50 100 200 500 1000 2000 5000 10000 20000];
            app.UIAxes.XScale = 'log';
            app.UIAxes.XTickLabel = {'20'; '50'; '100'; '200'; '500'; '1k'; '2k'; '5k'; '10k'; '20k'};
            app.UIAxes.YAxisLocation = 'right';
            app.UIAxes.YTick = [-24 -21 -18 -15 -12 -9 -6 -3 0 3 6 9 12];
            colormap(app.UIAxes, 'colorcube')
            app.AfegirfiltrePanel = uipanel(app.UIFigure);
            app.AfegirfiltrePanel.Title = 'Afegir filtre';
            app.AfegirfiltrePanel.Visible = 'off';
            app.TipusdefiltreDropDownLabel = uilabel(app.AfegirfiltrePanel);
            app.TipusdefiltreDropDownLabel.Text = 'Tipus de filtre';
            app.TipusdefiltreDropDown = uidropdown(app.AfegirfiltrePanel);
            app.TipusdefiltreDropDown.Items = {'Bell', 'Shelf', 'Band', 'HighCut', 'LowCut'};
            app.TipusdefiltreDropDown.Value = 'Bell';
            app.AfegirButton = uibutton(app.AfegirfiltrePanel, 'push');
            app.AfegirButton.ButtonPushedFcn = createCallbackFcn(app, @AfegirButtonPushed, true);
            app.AfegirButton.Text = '+ Afegir';
            app.XButton = uibutton(app.AfegirfiltrePanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.colorrgb094094094FiltreLabel = uilabel(app.UIFigure);
            app.colorrgb094094094FiltreLabel.Text = 'Filtre';
            app.FiltreDropDown = uidropdown(app.UIFigure);
            app.FiltreDropDown.Items = {'Selecciona un filtre'};
            app.FiltreDropDown.ItemsData = 0;
            app.FiltreDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltreDropDownValueChanged, true);
            app.FiltreDropDown.Value = 0;
            app.AudioSlider = uislider(app.UIFigure);
            app.AudioSlider.MajorTicks = [];
            app.AudioSlider.MajorTickLabels = {''};
            app.AudioSlider.ValueChangingFcn = createCallbackFcn(app, @AudioSliderValueChanging, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.Export = uipanel(app.UIFigure);
            app.Export.Title = 'Export audio';
            app.Export.Visible = 'off';
            app.FileNameEditFieldLabel = uilabel(app.Export);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.Export, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.ExporttoButton = uibutton(app.Export, 'push');
            app.ExporttoButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoButtonPushed, true);
            app.ExporttoButton.Text = 'Export to...';
            app.XButton_2 = uibutton(app.Export, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X';
            app.TipusLabel = uilabel(app.Export);
            app.TipusLabel.Text = 'Tipus';
            app.FormatDropDown = uidropdown(app.Export);
            app.FormatDropDown.Items = {'mp3', 'wav'};
            app.FormatDropDown.Value = 'mp3';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.AfegirfiltreMenu = uimenu(app.ContextMenu);
            app.AfegirfiltreMenu.MenuSelectedFcn = createCallbackFcn(app, @AfegirfiltreMenuSelected, true);
            app.AfegirfiltreMenu.Text = '+ Afegir filtre';
            % Assign app.ContextMenu
            app.UIAxes_2.ContextMenu = app.ContextMenu;
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end