classdef GoatsCorpSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GOATSCorpSoftwareUIFigure  matlab.ui.Figure
        AudioequalizerPanel        matlab.ui.container.Panel
        SelectaudiofileButton      matlab.ui.control.Button
        EqualizerButton            matlab.ui.control.Button
        AmplifierSimulatorButton   matlab.ui.control.Button
        MeasurmentsystemButton     matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        Eq;
        Ampli;
        MS;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectaudiofileButton
        function SelectaudiofileButtonPushed(app, event)
            [file, ~]=uigetfile({'*.wav'});
            app.Eq = EqApp(file);
            app.AudioequalizerPanel.Visible = 'off';
        end

        % Button pushed function: EqualizerButton
        function EqualizerButtonPushed(app, event)
            app.AudioequalizerPanel.Visible = 'on';
        end

        % Button pushed function: AmplifierSimulatorButton
        function AmplifierSimulatorButtonPushed(app, event)
            app.Ampli = AmpliApp;
        end

        % Button pushed function: MeasurmentsystemButton
        function MeasurmentsystemButtonPushed(app, event)
            app.MS = MSApp(app);
        end

        % Close request function: GOATSCorpSoftwareUIFigure
        function GOATSCorpSoftwareUIFigureCloseRequest(app, event)
            delete(app.Eq)
            delete(app.Ampli)
            delete(app.MS)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GOATSCorpSoftwareUIFigure = uifigure('Visible', 'off');
            app.GOATSCorpSoftwareUIFigure.Name = 'GOATS Corp. Software';
            app.GOATSCorpSoftwareUIFigure.Icon = fullfile(pathToMLAPP, 'GoatsGBase.png');
            app.GOATSCorpSoftwareUIFigure.CloseRequestFcn = createCallbackFcn(app, @GOATSCorpSoftwareUIFigureCloseRequest, true);
            app.Image = uiimage(app.GOATSCorpSoftwareUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MainAssets', 'GoatsLogoBlanc.png');
            app.MeasurmentsystemButton = uibutton(app.GOATSCorpSoftwareUIFigure, 'push');
            app.MeasurmentsystemButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurmentsystemButtonPushed, true);
            app.MeasurmentsystemButton.Icon = fullfile(pathToMLAPP, 'MainAssets', 'instrumBlanc.png');
            app.MeasurmentsystemButton.Text = 'Measurment system';
            app.AmplifierSimulatorButton = uibutton(app.GOATSCorpSoftwareUIFigure, 'push');
            app.AmplifierSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @AmplifierSimulatorButtonPushed, true);
            app.AmplifierSimulatorButton.Icon = fullfile(pathToMLAPP, 'MainAssets', 'amplifBlanc.png');
            app.AmplifierSimulatorButton.Text = 'Amplifier Simulator';
            app.EqualizerButton = uibutton(app.GOATSCorpSoftwareUIFigure, 'push');
            app.EqualizerButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizerButtonPushed, true);
            app.EqualizerButton.Icon = fullfile(pathToMLAPP, 'MainAssets', 'equBlanc.png');
            app.EqualizerButton.Text = 'Equalizer';
            app.AudioequalizerPanel = uipanel(app.GOATSCorpSoftwareUIFigure);
            app.AudioequalizerPanel.Title = 'Audio equalizer';
            app.AudioequalizerPanel.Visible = 'off';
            app.SelectaudiofileButton = uibutton(app.AudioequalizerPanel, 'push');
            app.SelectaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectaudiofileButtonPushed, true);
            app.SelectaudiofileButton.Text = 'Select audio file';
            % Show the figure after all components are created
            app.GOATSCorpSoftwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoatsCorpSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GOATSCorpSoftwareUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GOATSCorpSoftwareUIFigure)
        end
    end
end