classdef PanellBanda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        QKnob                        matlab.ui.control.Knob
        QKnobLabel                   matlab.ui.control.Label
        BWKnob                       matlab.ui.control.Knob
        FreqnciaHzKnob               matlab.ui.control.Knob
        FreqnciaHzKnobLabel          matlab.ui.control.Label
        BWKnobLabel                  matlab.ui.control.Label
        GuanydBKnob                  matlab.ui.control.Knob
        colorrgb094094094GuanyLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % App principal
        tipus = 3;
        ID ;

        fc_log;
        BW_log;
        Q;
        G;
        fs;
        N;

        filtre;
    end
    
    methods (Access = private)
        
        function actualitza(app)
            app.filtre = filtre_Banda(app, app.fc_log, app.BW_log, app.Q, app.G, app.fs, app.N);
            app.CallingApp.filtres(app.ID,:) =  app.filtre;
            app.CallingApp.actualitza_eq;
        end
        
        function H = filtre_bell(~,fc, Q, G, fs)

            % Guany lineal
            A = 10^(G/40);
            omega0 = 2*pi*fc/fs;
            alpha = sin(omega0)/(2*Q);

            % Coeficients del filtre biquad (peak/notch)
            b0 = 1 + alpha*A;
            b1 = -2*cos(omega0);
            b2 = 1 - alpha*A;
            a0 = 1 + alpha/A;
            a1 = -2*cos(omega0);
            a2 = 1 - alpha/A;

            % Normalització
            b = [b0, b1, b2] / a0;
            a = [1, a1/a0, a2/a0];

            % Vector de frecuencies normalizades (0 a pi)
            w = linspace(0, pi, fs/2);

            % Evaluar la funció de transferencia
            H = zeros(1, fs/2);
            for k = 1:fs/2
                ejw = exp(-1j*w(k));
                ejw2 = exp(-2j*w(k));
                num = b(1) + b(2)*ejw + b(3)*ejw2;
                den = a(1) + a(2)*ejw + a(3)*ejw2;
                H(k) = num / den;
            end
        end

        function H = filtre_Banda(app, fc, BW, Q, G, fs, N)
            A = 10^(G/20);
            f = logspace(log10(10), log10(fs/2), N);
            flin = linspace(0, fs/2, fs/2);
            H3 = A.*ones(1,fs/2);
            if 10^(fc-BW/2)>10
                H1 = app.filtre_bell(10^(fc-BW/2), Q, G, fs);
                H = [H1(1: round(10^(fc-BW/2)-1)) H3(round(10^(fc-BW/2)-1): fs/2)];
            else
                H = H3;
            end
            if 10^(fc+BW/2)<fs/2
                H2 = app.filtre_bell(10^(fc+BW/2), Q, G, fs);
                H = [H(1: round(10^(fc+BW/2)+1)) H2(round(10^(fc+BW/2)+1):fs/2)];
            end
            H = H(1:fs/2);
            H = interp1(flin, H, f, "spline");
        end
        

    end
    
    methods (Access = public)
        function valors(app)
            app.CallingApp.filtresParam(app.ID,:) = [app.ID, 10^app.fc_log, 10^app.BW_log, app.Q, app.G, app.tipus];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ID, fc, BW, Q, G, fs, N)
            app.CallingApp = mainapp;
            app.ID = ID;
            app.fc_log = log10(fc);
            app.BWKnob.Value = log10(BW);
            app.BW_log = log10(BW);
            app.FreqnciaHzKnob.Value = log10(fc);
            app.Q = Q;
            app.QKnob.Value = log10(Q);
            app.G = G;
            app.GuanydBKnob.Value = G;
            app.fs = fs;
            app.N = N;
            app.actualitza;
        end

        % Value changing function: GuanydBKnob
        function GuanydBKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.G = changingValue;
            app.actualitza;
        end

        % Value changing function: BWKnob
        function BWKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.BW_log = changingValue;
            app.actualitza;
        end

        % Value changing function: FreqnciaHzKnob
        function FreqnciaHzKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.fc_log = changingValue;
            app.actualitza;
        end

        % Value changing function: QKnob
        function QKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.Q = 10^changingValue;
            app.actualitza;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.valors;
            delete(app)
        end

        % Value changed function: BWKnob
        function BWKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
        end

        % Value changed function: FreqnciaHzKnob
        function FreqnciaHzKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
        end

        % Value changed function: GuanydBKnob
        function GuanydBKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
        end

        % Value changed function: QKnob
        function QKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.colorrgb094094094GuanyLabel = uilabel(app.UIFigure);
            app.colorrgb094094094GuanyLabel.Text = 'Guany (dB)';
            app.GuanydBKnob = uiknob(app.UIFigure, 'continuous');
            app.GuanydBKnob.Limits = [-30 30];
            app.GuanydBKnob.MajorTicks = [-30 -20 -10 0 10 20 30];
            app.GuanydBKnob.MajorTickLabels = {'-30', '', '', '', '', '', '30'};
            app.GuanydBKnob.ValueChangedFcn = createCallbackFcn(app, @GuanydBKnobValueChanged, true);
            app.GuanydBKnob.ValueChangingFcn = createCallbackFcn(app, @GuanydBKnobValueChanging, true);
            app.GuanydBKnob.MinorTicks = [-25 -15 -5 5 15 25];
            app.GuanydBKnob.Value = 10;
            app.BWKnobLabel = uilabel(app.UIFigure);
            app.BWKnobLabel.Text = 'BW';
            app.FreqnciaHzKnobLabel = uilabel(app.UIFigure);
            app.FreqnciaHzKnobLabel.Text = 'Freqüència (Hz)';
            app.FreqnciaHzKnob = uiknob(app.UIFigure, 'continuous');
            app.FreqnciaHzKnob.Limits = [1.3 4.38];
            app.FreqnciaHzKnob.MajorTicks = [1.3 1.916 2.532 3.148 3.764 4.38];
            app.FreqnciaHzKnob.MajorTickLabels = {'20', '', '', '', '', '24k'};
            app.FreqnciaHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqnciaHzKnobValueChanged, true);
            app.FreqnciaHzKnob.ValueChangingFcn = createCallbackFcn(app, @FreqnciaHzKnobValueChanging, true);
            app.FreqnciaHzKnob.MinorTicks = [-1.6 -1.5 -1.4 -1.3 -1.2 -1.1 -1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.0999999999999999 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6];
            app.FreqnciaHzKnob.Value = 3;
            app.BWKnob = uiknob(app.UIFigure, 'continuous');
            app.BWKnob.Limits = [0 3];
            app.BWKnob.MajorTickLabels = {''};
            app.BWKnob.ValueChangedFcn = createCallbackFcn(app, @BWKnobValueChanged, true);
            app.BWKnob.ValueChangingFcn = createCallbackFcn(app, @BWKnobValueChanging, true);
            app.BWKnob.Value = 0.3;
            app.QKnobLabel = uilabel(app.UIFigure);
            app.QKnobLabel.Text = 'Q';
            app.QKnob = uiknob(app.UIFigure, 'continuous');
            app.QKnob.Limits = [-1.6 1.6];
            app.QKnob.MajorTicks = [-1.6 -1.3 -1 -0.7 -0.4 0 0.4 0.7 1 1.3 1.6];
            app.QKnob.MajorTickLabels = {'0.025', '', '', '', '', '', '', '', '', '', '40'};
            app.QKnob.ValueChangedFcn = createCallbackFcn(app, @QKnobValueChanged, true);
            app.QKnob.ValueChangingFcn = createCallbackFcn(app, @QKnobValueChanging, true);
            app.QKnob.MinorTicks = [-1.6 -1.5 -1.4 -1.3 -1.2 -1.1 -1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.0999999999999999 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6];
            app.QKnob.Value = 0.45;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PanellBanda(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end