classdef PanellBell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        QKnob                        matlab.ui.control.Knob
        QKnobLabel                   matlab.ui.control.Label
        GuanydBKnob                  matlab.ui.control.Knob
        colorrgb094094094GuanyLabel  matlab.ui.control.Label
        FreqnciaHzKnob               matlab.ui.control.Knob
        FreqnciaHzKnobLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % App principal
        tipus = 1;
        ID ;

        fc;
        Q;
        G;
        fs;
        N;

        filtre;
    end
    
    methods (Access = private)
        
        function actualitza(app)
            app.filtre = filtre_bell(app,app.fc, app.Q, app.G, app.fs, app.N);
            app.CallingApp.filtres(app.ID,:) =  app.filtre;
            app.CallingApp.actualitza_eq;
        end

        function H = filtre_bell(~,fc, Q, G, fs, N)

            % Guany lineal
            A = 10^(G/40);
            omega0 = 2*pi*fc/fs;
            alpha = sin(omega0)/(2*Q);

            % Coeficients del filtre
            b0 = 1 + alpha*A;
            b1 = -2*cos(omega0);
            b2 = 1 - alpha*A;
            a0 = 1 + alpha/A;
            a1 = -2*cos(omega0);
            a2 = 1 - alpha/A;

            % Normalització
            b = [b0, b1, b2] / a0;
            a = [1, a1/a0, a2/a0];

            % Vector de frecuencies normalitzades
            f = logspace(log10(10), log10(fs/2), N);
            w = 2*pi*f/fs;

            % Funció de transferència
            H = zeros(1, N);
            for k = 1:N
                ejw = exp(-1j*w(k));
                ejw2 = exp(-2j*w(k));
                num = b(1) + b(2)*ejw + b(3)*ejw2;
                den = a(1) + a(2)*ejw + a(3)*ejw2;
                H(k) = num / den;
            end
        end
 
    end
    
    methods (Access = public)
        function valors(app)
            app.CallingApp.filtresParam(app.ID,:) = [app.ID, app.fc, app.Q, app.G, 0, app.tipus];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ID, fc, Q, A, fs, N)
            app.CallingApp = mainapp;
            app.ID = ID;
            app.fc = fc;
            app.FreqnciaHzKnob.Value = log10(fc);
            app.Q = Q;
            app.QKnob.Value = log10(Q);
            app.G = A;
            app.GuanydBKnob.Value = A;
            app.fs = fs;
            app.N = N;
            app.actualitza;
        end

        % Value changing function: FreqnciaHzKnob
        function FreqnciaHzKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.fc = 10^changingValue;
            app.actualitza;
        end

        % Value changing function: GuanydBKnob
        function GuanydBKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.G = changingValue;
            app.actualitza;
        end

        % Value changing function: QKnob
        function QKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.Q = 10^changingValue;
            app.actualitza;
        end

        % Close request function: UIFigure
        function PanellGaussCloseRequest(app, event)
            app.valors;
            delete(app)
        end

        % Value changed function: FreqnciaHzKnob
        function FreqnciaHzKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
            app.actualitza;
        end

        % Value changed function: GuanydBKnob
        function GuanydBKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
            app.actualitza;
        end

        % Value changed function: QKnob
        function QKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
            app.actualitza;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @PanellGaussCloseRequest, true);
            app.FreqnciaHzKnobLabel = uilabel(app.UIFigure);
            app.FreqnciaHzKnobLabel.Text = 'Freqüència (Hz)';
            app.FreqnciaHzKnob = uiknob(app.UIFigure, 'continuous');
            app.FreqnciaHzKnob.Limits = [1.3 4.38];
            app.FreqnciaHzKnob.MajorTicks = [1.3 1.916 2.532 3.148 3.764 4.38];
            app.FreqnciaHzKnob.MajorTickLabels = {'20', '', '', '', '', '24k'};
            app.FreqnciaHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqnciaHzKnobValueChanged, true);
            app.FreqnciaHzKnob.ValueChangingFcn = createCallbackFcn(app, @FreqnciaHzKnobValueChanging, true);
            app.FreqnciaHzKnob.MinorTicks = [1.608 2.224 2.84 3.456 4.072];
            app.FreqnciaHzKnob.Value = 2.84;
            app.colorrgb094094094GuanyLabel = uilabel(app.UIFigure);
            app.colorrgb094094094GuanyLabel.Text = 'Guany (dB)';
            app.GuanydBKnob = uiknob(app.UIFigure, 'continuous');
            app.GuanydBKnob.Limits = [-30 30];
            app.GuanydBKnob.MajorTicks = [-30 -20 -10 0 10 20 30];
            app.GuanydBKnob.MajorTickLabels = {'-30', '', '', '', '', '', '30'};
            app.GuanydBKnob.ValueChangedFcn = createCallbackFcn(app, @GuanydBKnobValueChanged, true);
            app.GuanydBKnob.ValueChangingFcn = createCallbackFcn(app, @GuanydBKnobValueChanging, true);
            app.GuanydBKnob.MinorTicks = [-25 -15 -5 5 15 25];
            app.GuanydBKnob.Value = 3;
            app.QKnobLabel = uilabel(app.UIFigure);
            app.QKnobLabel.Text = 'Q';
            app.QKnob = uiknob(app.UIFigure, 'continuous');
            app.QKnob.Limits = [-1.6 1.6];
            app.QKnob.MajorTicks = [-1.6 -1.3 -1 -0.7 -0.4 0 0.4 0.7 1 1.3 1.6];
            app.QKnob.MajorTickLabels = {'0.025', '', '', '', '', '', '', '', '', '', '40'};
            app.QKnob.ValueChangedFcn = createCallbackFcn(app, @QKnobValueChanged, true);
            app.QKnob.ValueChangingFcn = createCallbackFcn(app, @QKnobValueChanging, true);
            app.QKnob.MinorTicks = [-1.6 -1.5 -1.4 -1.3 -1.2 -1.1 -1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.0999999999999999 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PanellBell(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end