classdef PanellPA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FreqnciaHzKnob       matlab.ui.control.Knob
        FreqnciaHzKnobLabel  matlab.ui.control.Label
    end

    
     properties (Access = private)
        CallingApp % App principal
        tipus = 5;
        ID ;

        fc;
        fs;
        N;

        filtre;
    end
    
    methods (Access = private)
        
        function actualitza(app)
            app.filtre = butterworth_highpass_freq_response(app, app.fc, app.fs, app.N);
            app.CallingApp.filtres(app.ID,:) =  app.filtre;
            app.CallingApp.actualitza_eq;
        end

        function H = butterworth_highpass_freq_response(app, fc, fs, N)

            % Cálculo de coeficientes Butterworth 2º orden sin toolbox (pasa altos)
            K = tan(pi * fc /fs);
            norm = 1 + sqrt(2)*K + K^2;
            b0 = 1 / norm;
            b1 = -2 / norm;
            b2 = 1 / norm;
            a1 = 2 * (K^2 - 1) / norm;
            a2 = (1 - sqrt(2)*K + K^2) / norm;

            b = [b0, b1, b2];
            a = [1, a1, a2];

            % Transformación pasa bajos -> pasa altos (inversión de coeficientes b)
            b = [b2, b1, b0];

            % Vector de frecuencias de 0 a fs/2
            f = logspace(log10(10), log10(fs/2), N);

            % Evaluar H(e^{jω}) para cada frecuencia
            H = zeros(1, N);
            for k = 1:N
                omega = 2*pi*f(k)/fs;
                ejw = exp(-1j*omega);
                ejw2 = exp(-2j*omega);
                num = b(1) + b(2)*ejw + b(3)*ejw2;
                den = a(1) + a(2)*ejw + a(3)*ejw2;
                H(k) = num / den;
            end
        end
 
    end
    
    methods (Access = public)
        function valors(app)
            app.CallingApp.filtresParam(app.ID,:) = [app.ID, app.fc, 0, 0, 0, app.tipus];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ID, fc, fs, N)
            app.CallingApp = mainapp;
            app.ID = ID;
            app.fc =fc;
            app.FreqnciaHzKnob.Value = log10(fc);
            app.fs = fs;
            app.N = N;
            app.actualitza;
        end

        % Value changing function: FreqnciaHzKnob
        function FreqnciaHzKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.fc = 10^changingValue;
            app.actualitza;
        end

        % Close request function: UIFigure
        function PanellGaussCloseRequest(app, event)
            app.valors;
            delete(app)
        end

        % Value changed function: FreqnciaHzKnob
        function FreqnciaHzKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
            app.actualitza;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @PanellGaussCloseRequest, true);
            app.FreqnciaHzKnobLabel = uilabel(app.UIFigure);
            app.FreqnciaHzKnobLabel.Text = 'Freqüència (Hz)';
            app.FreqnciaHzKnob = uiknob(app.UIFigure, 'continuous');
            app.FreqnciaHzKnob.Limits = [1.3 4.38];
            app.FreqnciaHzKnob.MajorTicks = [1.3 1.916 2.532 3.148 3.764 4.38];
            app.FreqnciaHzKnob.MajorTickLabels = {'20', '', '', '', '', '24k'};
            app.FreqnciaHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqnciaHzKnobValueChanged, true);
            app.FreqnciaHzKnob.ValueChangingFcn = createCallbackFcn(app, @FreqnciaHzKnobValueChanging, true);
            app.FreqnciaHzKnob.MinorTicks = [1.608 2.224 2.84 3.456 4.072];
            app.FreqnciaHzKnob.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PanellPA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end