classdef PanellShelf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TypeSwitch                   matlab.ui.control.ToggleSwitch
        TypeSwitchLabel              matlab.ui.control.Label
        SlopeKnob                    matlab.ui.control.Knob
        SlopeKnobLabel               matlab.ui.control.Label
        GuanydBKnob                  matlab.ui.control.Knob
        colorrgb094094094GuanyLabel  matlab.ui.control.Label
        FreqnciaHzKnob               matlab.ui.control.Knob
        FreqnciaHzKnobLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % App principal
        tipus = 2;
        ID ;

        fc;
        S;
        G;
        lH;
        fs;
        N;

        filtre;
    end
    
    methods (Access = private)
        
        function actualitza(app)
            app.filtre = filtre_shelf(app,app.fc, app.S, app.G, app.lH, app.fs, app.N);
            app.CallingApp.filtres(app.ID,:) = app.filtre;
            app.CallingApp.actualitza_eq;
        end

        function H = filtre_shelf(~,fc, S, G, lH, fs, N)
            f = logspace(log10(10), log10(fs/2), N);
            lowHigh = (-1)^lH;

            % Guany lineal
            A = 10^(G/40);

            % Constants
            omega0 = 2*pi*fc/fs;
            alpha = sin(omega0)/2 * sqrt( (A + 1/A)*(1/S - 1) + 2 );

            % Coeficients del filtre
            b0 =    A*((A+1) - lowHigh*(A-1)*cos(omega0) + 2*sqrt(A)*alpha);
            b1 =  lowHigh*2*A*((A-1) - lowHigh*(A+1)*cos(omega0));
            b2 =    A*((A+1) - lowHigh*(A-1)*cos(omega0) - 2*sqrt(A)*alpha);
            a0 =       (A+1) + lowHigh*(A-1)*cos(omega0) + 2*sqrt(A)*alpha;
            a1 = -2*lowHigh*((A-1) + lowHigh*(A+1)*cos(omega0));
            a2 =       (A+1) + lowHigh*(A-1)*cos(omega0) - 2*sqrt(A)*alpha;

            % Normalitzar coeficiens
            b = [b0, b1, b2] / a0;
            a = [1, a1/a0, a2/a0];

            % Funció de transferència
            H = zeros(1, N);
            for k = 1:N
                omega = 2*pi*f(k)/fs;
                ejw = exp(-1j*omega);
                ejw2 = exp(-2j*omega);
                num = b(1) + b(2)*ejw + b(3)*ejw2;
                den = a(1) + a(2)*ejw + a(3)*ejw2;
                H(k) = num / den;
            end
        end
 
    end
    
    methods (Access = public)
        function valors(app)
            app.CallingApp.filtresParam(app.ID,:) = [app.ID, app.fc, app.S, app.G, app.lH, app.tipus];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ID, fc, S, G, lH, fs, N)
            app.CallingApp = mainapp;
            app.ID = ID;
            app.fc = fc;
            app.FreqnciaHzKnob.Value = log10(fc);
            app.S = S;
            app.SlopeKnob.Value = S;
            app.G = G;
            app.GuanydBKnob.Value = G;
            app.lH = lH;
            app.TypeSwitch.ValueIndex = 1;
            app.fs = fs;
            app.N = N;
            app.actualitza;
        end

        % Value changing function: FreqnciaHzKnob
        function FreqnciaHzKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.fc = 10^changingValue;
            app.actualitza;
        end

        % Value changing function: GuanydBKnob
        function GuanydBKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.G = changingValue;
            app.actualitza;
        end

        % Value changing function: SlopeKnob
        function SlopeKnobValueChanging(app, event)
            app.CallingApp.isChanging=1;
            changingValue = event.Value;
            app.S = changingValue;
            app.actualitza;
        end

        % Close request function: UIFigure
        function PanellGaussCloseRequest(app, event)
            app.valors;
            delete(app)
        end

        % Value changed function: TypeSwitch
        function TypeSwitchValueChanged(app, event)
            app.CallingApp.isChanging=0;
            value = app.TypeSwitch.Value;
            app.lH = value;
            app.actualitza;
        end

        % Value changed function: FreqnciaHzKnob
        function FreqnciaHzKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
            app.actualitza;
        end

        % Value changed function: GuanydBKnob
        function GuanydBKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
            app.actualitza;
        end

        % Value changed function: SlopeKnob
        function SlopeKnobValueChanged(app, event)
            app.CallingApp.isChanging=0;
            app.actualitza;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @PanellGaussCloseRequest, true);
            app.FreqnciaHzKnobLabel = uilabel(app.UIFigure);
            app.FreqnciaHzKnobLabel.Text = 'Freqüència (Hz)';
            app.FreqnciaHzKnob = uiknob(app.UIFigure, 'continuous');
            app.FreqnciaHzKnob.Limits = [1.3 4.38];
            app.FreqnciaHzKnob.MajorTicks = [1.3 1.916 2.532 3.148 3.764 4.38];
            app.FreqnciaHzKnob.MajorTickLabels = {'20', '', '', '', '', '24k'};
            app.FreqnciaHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqnciaHzKnobValueChanged, true);
            app.FreqnciaHzKnob.ValueChangingFcn = createCallbackFcn(app, @FreqnciaHzKnobValueChanging, true);
            app.FreqnciaHzKnob.MinorTicks = [1.608 2.224 2.84 3.456 4.072];
            app.FreqnciaHzKnob.Value = 3;
            app.colorrgb094094094GuanyLabel = uilabel(app.UIFigure);
            app.colorrgb094094094GuanyLabel.Text = 'Guany (dB)';
            app.GuanydBKnob = uiknob(app.UIFigure, 'continuous');
            app.GuanydBKnob.Limits = [-30 30];
            app.GuanydBKnob.MajorTicks = [-30 -20 -10 0 10 20 30];
            app.GuanydBKnob.MajorTickLabels = {'-30', '', '', '', '', '', '30'};
            app.GuanydBKnob.ValueChangedFcn = createCallbackFcn(app, @GuanydBKnobValueChanged, true);
            app.GuanydBKnob.ValueChangingFcn = createCallbackFcn(app, @GuanydBKnobValueChanging, true);
            app.GuanydBKnob.MinorTicks = [-25 -15 -5 5 15 25];
            app.GuanydBKnob.Value = 10;
            app.SlopeKnobLabel = uilabel(app.UIFigure);
            app.SlopeKnobLabel.Text = 'Slope';
            app.SlopeKnob = uiknob(app.UIFigure, 'continuous');
            app.SlopeKnob.Limits = [0.3 2];
            app.SlopeKnob.MajorTicks = [0.3 0.5425 0.785 1.0275 1.27 1.5125 1.755 2];
            app.SlopeKnob.MajorTickLabels = {'0.3', '', '', '', '', '', '', '2'};
            app.SlopeKnob.ValueChangedFcn = createCallbackFcn(app, @SlopeKnobValueChanged, true);
            app.SlopeKnob.ValueChangingFcn = createCallbackFcn(app, @SlopeKnobValueChanging, true);
            app.SlopeKnob.MinorTicks = [0.3 0.3606 0.4212 0.4818 0.5424 0.603 0.6636 0.7242 0.7848 0.8454 0.906 0.9666 1.0272 1.0878 1.1484 1.209 1.2696 1.3302 1.3908 1.4514 1.512 1.5726 1.6332 1.6938 1.7544 1.815 1.8756 1.9362 2];
            app.SlopeKnob.Value = 0.75;
            app.TypeSwitchLabel = uilabel(app.UIFigure);
            app.TypeSwitchLabel.Text = 'Type';
            app.TypeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.TypeSwitch.Items = {'Low', 'High'};
            app.TypeSwitch.ItemsData = {'0', '1'};
            app.TypeSwitch.ValueChangedFcn = createCallbackFcn(app, @TypeSwitchValueChanged, true);
            app.TypeSwitch.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PanellShelf(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end